classdef NF_Fit < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        ModelPanel                      matlab.ui.container.Panel
        ReadyLamp                       matlab.ui.control.Lamp
        ReadyLampLabel                  matlab.ui.control.Label
        Switch_2                        matlab.ui.control.Switch
        StepSizeDropDown_2              matlab.ui.control.DropDown
        StepSizeDropDown_2Label         matlab.ui.control.Label
        BreakpointSpinner               matlab.ui.control.Spinner
        BreakpointSpinnerLabel          matlab.ui.control.Label
        SimTimeEditField                matlab.ui.control.NumericEditField
        SimTimeEditFieldLabel           matlab.ui.control.Label
        eDoxRangeEditField              matlab.ui.control.EditField
        eDoxRangeEditFieldLabel         matlab.ui.control.Label
        ParametersPanel                 matlab.ui.container.Panel
        StepSizeDropDown                matlab.ui.control.DropDown
        StepSizeDropDownLabel           matlab.ui.control.Label
        Switch                          matlab.ui.control.Switch
        cSpinner                        matlab.ui.control.Spinner
        cSpinnerLabel                   matlab.ui.control.Label
        fSpinner                        matlab.ui.control.Spinner
        fSpinnerLabel                   matlab.ui.control.Label
        rSpinner                        matlab.ui.control.Spinner
        rSpinnerLabel                   matlab.ui.control.Label
        rhoSpinner                      matlab.ui.control.Spinner
        rhoSpinnerLabel                 matlab.ui.control.Label
        alphaSpinner                    matlab.ui.control.Spinner
        alphaSpinnerLabel               matlab.ui.control.Label
        aSpinner                        matlab.ui.control.Spinner
        aSpinnerLabel                   matlab.ui.control.Label
        deltaSpinner                    matlab.ui.control.Spinner
        deltaSpinnerLabel               matlab.ui.control.Label
        pSpinner                        matlab.ui.control.Spinner
        pSpinnerLabel                   matlab.ui.control.Label
        muSpinner                       matlab.ui.control.Spinner
        muSpinnerLabel                  matlab.ui.control.Label
        mSpinner                        matlab.ui.control.Spinner
        mSpinnerLabel                   matlab.ui.control.Label
        lkSpinner                       matlab.ui.control.Spinner
        lkSpinnerLabel                  matlab.ui.control.Label
        bSpinner                        matlab.ui.control.Spinner
        bSpinnerLabel                   matlab.ui.control.Label
        PiecewiseLinearRegressionPanel  matlab.ui.container.Panel
        RatioB2A1EditField              matlab.ui.control.NumericEditField
        RatioB2A1EditFieldLabel         matlab.ui.control.Label
        R2EditField_2                   matlab.ui.control.NumericEditField
        R2EditField_2Label              matlab.ui.control.Label
        R2EditField                     matlab.ui.control.NumericEditField
        R2EditFieldLabel                matlab.ui.control.Label
        Eq2EditField                    matlab.ui.control.EditField
        Eq2EditFieldLabel               matlab.ui.control.Label
        Eq1EditField                    matlab.ui.control.EditField
        Eq1EditFieldLabel               matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        dizzy_text % Model text
    end
    
    methods (Access = private)
        
        function SIMULATE(app)
            
            % Change Lamp
            set(app.ReadyLamp,'Color',[1,0,0]);
            set(app.ReadyLampLabel,'Text','Wait');
            pause(0.01)


            % Create SimBiology model from dizzy file and parameters
            Mobj = DIZZY2MATLAB(app,app.dizzy_text);

            % Initial conditions
            SimTime = app.SimTimeEditField.Value;
            eDoxRange = eval(app.eDoxRangeEditField.Value);
            aveP = zeros(1,length(eDoxRange));
            stdP = aveP;
            ctr = 1;

            % Simulate the model
            for eDox=eDoxRange
    set(Mobj.Species(1),'InitialAmount',eDox) % Sets initial amount of eDox
    cs = getconfigset(Mobj); %configuration?
    switch app.Switch_2.Value %set solver type
        case 'ODE'
            set(cs,'SolverType','sundials')
            cs.SolverOptions.RelativeTolerance = 0.01;
            cs.SolverOptions.AbsoluteToleranceScaling = false;
        case 'SSA'
            set(cs,'SolverType','ssa')
    end
    set(cs,'MaximumNumberofLogs',10^24);
    set(cs, 'StopTime', SimTime); %set stop time 
    [t, x,names] = sbiosimulate(Mobj,cs); %simulate model with times, values, and names
    Index = find(contains(names, 'Q')); %find Q
    names(Index)=[]; %get rid of Q
    x(:,Index)=[]; %get rid of Q
    IndexD = find(contains(names, 'D'));
    IndexH = find(contains(names, 'H'));
    IndexB = find(contains(names, 'B'));
    D=x(:,IndexD); %find vales for D
    H=x(:,IndexH); %find values for H
    B=x(:,IndexB); %find values for B

    P=D+H+B;
    % Debug - plot time course
    %f1 = figure; ax = axes(f1); plot(ax,t,P)


    switch app.Switch_2.Value % Save values based on sim type
        case 'ODE'
            aveP(ctr) = mean(P(end-10:end));
            stdP(ctr) = std(P(end-10:end));
        case 'SSA'
            start_time = find(t(t>=0.1*SimTime)); start_time = start_time(end); % Find start of index at last 10% of simulation
            aveP(ctr) = mean(P(start_time:end));
            stdP(ctr) = std(P(start_time:end));
            % Debug - plot vertical line for last 10% of simulation
            %max_val = max(P); min_val = min(P);
            %ydata = linspace(min_val,max_val,100);
            %hold on; plot(ax,t(start_time).*ones(1,length(ydata)),ydata,'k-');
            %title(num2str(eDox));
    end

    ctr = ctr + 1;
            end
      

      switch app.Switch_2.Value % Plot on axis
          case 'ODE'
            % Plot on axis
            cla(app.UIAxes);
            plot(app.UIAxes,eDoxRange,aveP,'ko-','MarkerFaceColor','k');
            hold(app.UIAxes,'on');
            set(app.UIAxes.YLabel, 'String', '<P>')

            % Perform PLnR and update
            PLnR(app,eDoxRange,aveP);
          case 'SSA'
              cla(app.UIAxes);
              plot(app.UIAxes,eDoxRange,stdP./aveP, 'bo-', 'MarkerFaceColor', 'b');
              set(app.UIAxes.YLabel, 'String', 'CV(P)')
      end

            % Change Lamp
            set(app.ReadyLamp,'Color',[0,1,0]);
            set(app.ReadyLampLabel,'Text','Ready');

        end
        
        
        function Mobj = DIZZY2MATLAB(app,intext)

%separate lines by searching for ;
lines=regexp(intext,'([^\;]+\;)','tokens');
lines=vertcat(lines{:});

%Search for lines with equal signs-those are declarations. Everything else
%is a reaction
declarations=regexp(lines,'=');
reactionlines=cellfun('isempty',declarations);
declarations=lines(not(reactionlines));
reactions=lines(reactionlines);

%Split reactions based on , or ;
%The second result is the reaction rule, i.e. A->B+C, while the third
%result is the reaction rate
reactions=regexp(reactions,'([^,|^;]+)','tokens');
reactions=vertcat(reactions{:});

reactionrule=reactions(:,1);
reactionrule=vertcat(reactionrule{:});

reactionrate=reactions(:,2);
reactionrate=vertcat(reactionrate{:});

% Change parameters

prmvals = GETPARAMVALUES(app);
if strcmp(app.Switch.Value,'Value') % Convert to mults if needed
    prmvals = prmvals./[15,2,200,3.5,75,0.046,3.7,0.28,20.8,0.2,0.9242,140];
end
for ii=1:12
    declarations{ii} = strcat(declarations{ii}(1:end-1),'*',num2str(prmvals(ii)),';');
end


% Create Mobj based on cell arrays
Mobj = createMobj(declarations,reactionrule,reactionrate);

        end
        
        function PLnR(app,x,y)
            
            % Initialization
            c = app.BreakpointSpinner.Value;
            x1 = x(x<=c); x2 = x(x>c);
            y1 = y(1:length(x1)); y2 = y(length(x1)+1:end);
            n1 = length(x1); n2 = length(x2);
            sumx1 = sum(x1); sumx2 = sum(x2);
            sumx1sq = sum(x1.^2); sumx2sq = sum(x2.^2);
            sumx1y1 = sum(x1.*y1); sumx2y2 = sum(x2.*y2);
            sumy1 = sum(y1); sumy2 = sum(y2);

            % Solve for A1 and B1
            RR1 = ([sumx1sq,sumx1;sumx1,n1]^-1)*[sumx1y1;sumy1];
            A1 = RR1(1); B1 = RR1(2);

            % Solve for A2 and B2
            top = sumx2y2 - (B1+c*A1)*sumx2 - c*sumy2 + c*B1*n2 + (c^2)*A1*n2;
            bot = sumx2sq - 2*c*sumx2 + (c^2)*n2;
            A2 = top/bot; B2 = B1 + c*(A1-A2);

            % Calculate correlations
            K = [A1,B1;A2,B2];
            RR2 = corrcoef(y1,polyval(K(1,:),x1)); R(1) = RR2(1,2);
            RR3 = corrcoef(y2,polyval(K(2,:),x2)); R(2) = RR3(1,2);
            R = R.^2;

            % Plot and update
            plot(app.UIAxes,[x1,c],polyval(K(1,:),[x1,c]),'r--');
            plot(app.UIAxes,[c,x2],polyval(K(2,:),[c,x2]),'b--');
            eq1 = strcat(num2str(K(1,1)),'x + ',num2str(K(1,2)));
            eq2 = strcat(num2str(K(2,1)),'x + ',num2str(K(2,2)));
            set(app.Eq1EditField,'Value',eq1); set(app.Eq2EditField,'Value',eq2);
            set(app.R2EditField,'Value',R(1)); set(app.R2EditField_2,'Value',R(2));
            set(app.RatioB2A1EditField,'Value',K(2,2)/K(1,1));


        end
        
        function prmvals = GETPARAMVALUES(app)
            params = {'b','lk','m','mu','p','delta','a','alpha','rho','r','f','c'};
            prmvals = zeros(1,12);
            for ii=1:12
                name = strcat('app.',params{ii},'Spinner.Value');
                prmvals(ii) = eval(name);
            end
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Get dizzy text
            app.dizzy_text = SimpDiz('NF_Original.cmdl','Q');

            % Run model
            SIMULATE(app);
        end

        % Value changed function: bSpinner
        function bSpinnerValueChanged(app, event)
            SIMULATE(app);
        end

        % Value changed function: lkSpinner
        function lkSpinnerValueChanged(app, event)
            SIMULATE(app);
        end

        % Value changed function: mSpinner
        function mSpinnerValueChanged(app, event)
            SIMULATE(app);
        end

        % Value changed function: muSpinner
        function muSpinnerValueChanged(app, event)
            SIMULATE(app);
        end

        % Value changed function: pSpinner
        function pSpinnerValueChanged(app, event)
            SIMULATE(app);
        end

        % Value changed function: deltaSpinner
        function deltaSpinnerValueChanged(app, event)
            SIMULATE(app);
        end

        % Value changed function: aSpinner
        function aSpinnerValueChanged(app, event)
            SIMULATE(app);
        end

        % Value changed function: alphaSpinner
        function alphaSpinnerValueChanged(app, event)
            SIMULATE(app);
        end

        % Value changed function: rhoSpinner
        function rhoSpinnerValueChanged(app, event)
            SIMULATE(app);
        end

        % Value changed function: rSpinner
        function rSpinnerValueChanged(app, event)
            SIMULATE(app);
        end

        % Value changed function: fSpinner
        function fSpinnerValueChanged(app, event)
            SIMULATE(app);
        end

        % Value changed function: cSpinner
        function cSpinnerValueChanged(app, event)
            SIMULATE(app);
        end

        % Value changed function: eDoxRangeEditField
        function eDoxRangeEditFieldValueChanged(app, event)
            SIMULATE(app);
        end

        % Value changed function: SimTimeEditField
        function SimTimeEditFieldValueChanged(app, event)
            SIMULATE(app);
        end

        % Value changed function: BreakpointSpinner
        function BreakpointSpinnerValueChanged(app, event)
            SIMULATE(app);
        end

        % Value changed function: StepSizeDropDown
        function StepSizeDropDownValueChanged(app, event)
            % Change step size on all spinners in parameters panel
            stepsize = app.StepSizeDropDown.Value;
            params = {'b','lk','m','mu','p','delta','a','alpha','rho','r','f','c'};
            for ii=1:12
                name = strcat('app.',params{ii},'Spinner');
                set(eval(name),'Step',str2num(stepsize));
            end
        end

        % Value changed function: StepSizeDropDown_2
        function StepSizeDropDown_2ValueChanged(app, event)
            % Change step size on breakpoint spinner
            stepsize = app.StepSizeDropDown_2.Value;
            set(app.BreakpointSpinner,'Step',str2num(stepsize));
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            orgvals = [15,2,200,3.5,75,0.046,3.7,0.28,20.8,0.2,0.9242,140];
            params = {'b','lk','m','mu','p','delta','a','alpha','rho','r','f','c'};
            % From values to mults
            if strcmp(app.Switch.Value,'Mult')
                for ii=1:12
                    name = strcat('app.',params{ii},'Spinner');
                    val = eval(strcat(name,'.Value'));
                    set(eval(name),'Value',val/orgvals(ii));
                end
            % From mults to values
            else
                for ii=1:12
                    name = strcat('app.',params{ii},'Spinner');
                    val = eval(strcat(name,'.Value'));
                    set(eval(name),'Value',val*orgvals(ii));
                end
            end
        end

        % Value changed function: Switch_2
        function Switch_2ValueChanged(app, event)
            SIMULATE(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Dose Response')
            xlabel(app.UIAxes, 'eDox')
            ylabel(app.UIAxes, '<P>')
            zlabel(app.UIAxes, 'Z')
            app.PiecewiseLinearRegressionPanel = uipanel(app.UIFigure);
            app.PiecewiseLinearRegressionPanel.Title = 'Piecewise Linear Regression';
            app.Eq1EditFieldLabel = uilabel(app.PiecewiseLinearRegressionPanel);
            app.Eq1EditFieldLabel.Text = 'Eq1:';
            app.Eq1EditField = uieditfield(app.PiecewiseLinearRegressionPanel, 'text');
            app.Eq1EditField.Editable = 'off';
            app.Eq2EditFieldLabel = uilabel(app.PiecewiseLinearRegressionPanel);
            app.Eq2EditFieldLabel.Text = 'Eq2:';
            app.Eq2EditField = uieditfield(app.PiecewiseLinearRegressionPanel, 'text');
            app.Eq2EditField.Editable = 'off';
            app.R2EditFieldLabel = uilabel(app.PiecewiseLinearRegressionPanel);
            app.R2EditFieldLabel.Text = 'R^2:';
            app.R2EditField = uieditfield(app.PiecewiseLinearRegressionPanel, 'numeric');
            app.R2EditField.ValueDisplayFormat = '%.4f';
            app.R2EditField.Editable = 'off';
            app.R2EditField_2Label = uilabel(app.PiecewiseLinearRegressionPanel);
            app.R2EditField_2Label.Text = 'R^2:';
            app.R2EditField_2 = uieditfield(app.PiecewiseLinearRegressionPanel, 'numeric');
            app.R2EditField_2.ValueDisplayFormat = '%.4f';
            app.R2EditField_2.Editable = 'off';
            app.RatioB2A1EditFieldLabel = uilabel(app.PiecewiseLinearRegressionPanel);
            app.RatioB2A1EditFieldLabel.Text = 'Ratio (B2/A1)';
            app.RatioB2A1EditField = uieditfield(app.PiecewiseLinearRegressionPanel, 'numeric');
            app.RatioB2A1EditField.Editable = 'off';
            app.ParametersPanel = uipanel(app.UIFigure);
            app.ParametersPanel.Title = 'Parameters';
            app.bSpinnerLabel = uilabel(app.ParametersPanel);
            app.bSpinnerLabel.Text = 'b';
            app.bSpinner = uispinner(app.ParametersPanel);
            app.bSpinner.Limits = [0 Inf];
            app.bSpinner.ValueChangedFcn = createCallbackFcn(app, @bSpinnerValueChanged, true);
            app.bSpinner.Tooltip = {'Binding rate of inducer to tetR::EGFP'; 'Original value: 15'};
            app.bSpinner.Value = 15;
            app.lkSpinnerLabel = uilabel(app.ParametersPanel);
            app.lkSpinnerLabel.Text = 'lk';
            app.lkSpinner = uispinner(app.ParametersPanel);
            app.lkSpinner.Limits = [0 Inf];
            app.lkSpinner.ValueChangedFcn = createCallbackFcn(app, @lkSpinnerValueChanged, true);
            app.lkSpinner.Tooltip = {'Basal transcription rate (leakage)'; 'Original value: 2'};
            app.lkSpinner.Value = 2;
            app.mSpinnerLabel = uilabel(app.ParametersPanel);
            app.mSpinnerLabel.Text = 'm';
            app.mSpinner = uispinner(app.ParametersPanel);
            app.mSpinner.Limits = [0 Inf];
            app.mSpinner.ValueChangedFcn = createCallbackFcn(app, @mSpinnerValueChanged, true);
            app.mSpinner.Tooltip = {'Max transcription rate'; 'Original value: 200'};
            app.mSpinner.Value = 200;
            app.muSpinnerLabel = uilabel(app.ParametersPanel);
            app.muSpinnerLabel.Text = 'mu';
            app.muSpinner = uispinner(app.ParametersPanel);
            app.muSpinner.Limits = [0 Inf];
            app.muSpinner.ValueChangedFcn = createCallbackFcn(app, @muSpinnerValueChanged, true);
            app.muSpinner.Tooltip = {'mRNA degradation'; 'Original value: 3.5'};
            app.muSpinner.Value = 3.5;
            app.pSpinnerLabel = uilabel(app.ParametersPanel);
            app.pSpinnerLabel.Text = 'p';
            app.pSpinner = uispinner(app.ParametersPanel);
            app.pSpinner.Limits = [0 Inf];
            app.pSpinner.ValueChangedFcn = createCallbackFcn(app, @pSpinnerValueChanged, true);
            app.pSpinner.Tooltip = {'Translational rate'; 'Original value: 75'; 'Note: will be divided by 2 due to dimers'};
            app.pSpinner.Value = 75;
            app.deltaSpinnerLabel = uilabel(app.ParametersPanel);
            app.deltaSpinnerLabel.Text = 'delta';
            app.deltaSpinner = uispinner(app.ParametersPanel);
            app.deltaSpinner.Limits = [0 Inf];
            app.deltaSpinner.ValueChangedFcn = createCallbackFcn(app, @deltaSpinnerValueChanged, true);
            app.deltaSpinner.Tooltip = {'Protein degradation + dilution'; 'Original value: 0.046'};
            app.deltaSpinner.Value = 0.046;
            app.aSpinnerLabel = uilabel(app.ParametersPanel);
            app.aSpinnerLabel.Text = 'a';
            app.aSpinner = uispinner(app.ParametersPanel);
            app.aSpinner.Limits = [0 Inf];
            app.aSpinner.ValueChangedFcn = createCallbackFcn(app, @aSpinnerValueChanged, true);
            app.aSpinner.Tooltip = {'Transcriptional machinery assembling'; 'Original value: 3.7'};
            app.aSpinner.Value = 3.7;
            app.alphaSpinnerLabel = uilabel(app.ParametersPanel);
            app.alphaSpinnerLabel.Text = 'alpha';
            app.alphaSpinner = uispinner(app.ParametersPanel);
            app.alphaSpinner.Limits = [0 Inf];
            app.alphaSpinner.ValueChangedFcn = createCallbackFcn(app, @alphaSpinnerValueChanged, true);
            app.alphaSpinner.Tooltip = {'Transcriptional machinery disassembling'; 'Original value: 0.28'};
            app.alphaSpinner.Value = 0.28;
            app.rhoSpinnerLabel = uilabel(app.ParametersPanel);
            app.rhoSpinnerLabel.Text = 'rho';
            app.rhoSpinner = uispinner(app.ParametersPanel);
            app.rhoSpinner.Limits = [0 Inf];
            app.rhoSpinner.ValueChangedFcn = createCallbackFcn(app, @rhoSpinnerValueChanged, true);
            app.rhoSpinner.Tooltip = {'tetR::EGFP unbinding from promoter'; 'Original value: 20.8'};
            app.rhoSpinner.Value = 20.8;
            app.rSpinnerLabel = uilabel(app.ParametersPanel);
            app.rSpinnerLabel.Text = 'r';
            app.rSpinner = uispinner(app.ParametersPanel);
            app.rSpinner.Limits = [0 Inf];
            app.rSpinner.ValueChangedFcn = createCallbackFcn(app, @rSpinnerValueChanged, true);
            app.rSpinner.Tooltip = {'tetR::EGFP binding to promoter'; 'Original value: 0.2'};
            app.rSpinner.Value = 0.2;
            app.fSpinnerLabel = uilabel(app.ParametersPanel);
            app.fSpinnerLabel.Text = 'f';
            app.fSpinner = uispinner(app.ParametersPanel);
            app.fSpinner.Limits = [0 Inf];
            app.fSpinner.ValueChangedFcn = createCallbackFcn(app, @fSpinnerValueChanged, true);
            app.fSpinner.Tooltip = {'Rate of inducer entering cells'; 'Original value: 0.9242'};
            app.fSpinner.Value = 0.9242;
            app.cSpinnerLabel = uilabel(app.ParametersPanel);
            app.cSpinnerLabel.Text = 'c';
            app.cSpinner = uispinner(app.ParametersPanel);
            app.cSpinner.Limits = [0 Inf];
            app.cSpinner.ValueChangedFcn = createCallbackFcn(app, @cSpinnerValueChanged, true);
            app.cSpinner.Tooltip = {'Effective inducer concentration constant multiplier'; 'Original value: 140'};
            app.cSpinner.Value = 140;
            app.Switch = uiswitch(app.ParametersPanel, 'slider');
            app.Switch.Items = {'Value', 'Mult'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Value = 'Value';
            app.StepSizeDropDownLabel = uilabel(app.ParametersPanel);
            app.StepSizeDropDownLabel.Text = 'Step Size';
            app.StepSizeDropDown = uidropdown(app.ParametersPanel);
            app.StepSizeDropDown.Items = {'10', '1', '0.1', '0.01'};
            app.StepSizeDropDown.ValueChangedFcn = createCallbackFcn(app, @StepSizeDropDownValueChanged, true);
            app.StepSizeDropDown.Value = '1';
            app.ModelPanel = uipanel(app.UIFigure);
            app.ModelPanel.Title = 'Model';
            app.eDoxRangeEditFieldLabel = uilabel(app.ModelPanel);
            app.eDoxRangeEditFieldLabel.Text = 'eDoxRange';
            app.eDoxRangeEditField = uieditfield(app.ModelPanel, 'text');
            app.eDoxRangeEditField.ValueChangedFcn = createCallbackFcn(app, @eDoxRangeEditFieldValueChanged, true);
            app.eDoxRangeEditField.Value = '[0:0.5:5,10:15,20:10:100]';
            app.SimTimeEditFieldLabel = uilabel(app.ModelPanel);
            app.SimTimeEditFieldLabel.Text = 'SimTime';
            app.SimTimeEditField = uieditfield(app.ModelPanel, 'numeric');
            app.SimTimeEditField.Limits = [0 Inf];
            app.SimTimeEditField.ValueDisplayFormat = '%.0f';
            app.SimTimeEditField.ValueChangedFcn = createCallbackFcn(app, @SimTimeEditFieldValueChanged, true);
            app.SimTimeEditField.Value = 200;
            app.BreakpointSpinnerLabel = uilabel(app.ModelPanel);
            app.BreakpointSpinnerLabel.Text = 'Breakpoint';
            app.BreakpointSpinner = uispinner(app.ModelPanel);
            app.BreakpointSpinner.Limits = [0 Inf];
            app.BreakpointSpinner.ValueChangedFcn = createCallbackFcn(app, @BreakpointSpinnerValueChanged, true);
            app.BreakpointSpinner.Value = 31;
            app.StepSizeDropDown_2Label = uilabel(app.ModelPanel);
            app.StepSizeDropDown_2Label.Text = 'Step Size';
            app.StepSizeDropDown_2 = uidropdown(app.ModelPanel);
            app.StepSizeDropDown_2.Items = {'10', '1', '0.1', '0.01'};
            app.StepSizeDropDown_2.ValueChangedFcn = createCallbackFcn(app, @StepSizeDropDown_2ValueChanged, true);
            app.StepSizeDropDown_2.Value = '1';
            app.Switch_2 = uiswitch(app.ModelPanel, 'slider');
            app.Switch_2.Items = {'ODE', 'SSA'};
            app.Switch_2.ValueChangedFcn = createCallbackFcn(app, @Switch_2ValueChanged, true);
            app.Switch_2.Value = 'ODE';
            app.ReadyLampLabel = uilabel(app.ModelPanel);
            app.ReadyLampLabel.Text = 'Ready';
            app.ReadyLamp = uilamp(app.ModelPanel);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = NF_Fit

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end